classdef test_MLSweep_braking_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoaderBrakeTestUIFigure         matlab.ui.Figure
        LoadkgEditField                 matlab.ui.control.NumericEditField
        LoadkgEditFieldLabel            matlab.ui.control.Label
        MaxTimesecEditField             matlab.ui.control.NumericEditField
        MaxTimesecEditFieldLabel        matlab.ui.control.Label
        RunTestButton                   matlab.ui.control.Button
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        BucketTiltAngledegEditField     matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        BoomLiftAngledegEditField       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        SlopedegEditField               matlab.ui.control.NumericEditField
        SlopedegEditField_2Label        matlab.ui.control.Label
        SteeringAngledegEditField       matlab.ui.control.NumericEditField
        SteeringAngledegEditFieldLabel  matlab.ui.control.Label
        TargetSpeedkphEditField         matlab.ui.control.NumericEditField
        TargetSpeedkphEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunTestButton
        function RunTestButtonPushed(app, event)
            vSpd   = app.TargetSpeedkphEditField.Value;
            qSlope = app.SlopedegEditField.Value;
            qStr   = app.SteeringAngledegEditField.Value;
            qLift  = app.BoomLiftAngledegEditField.Value;
            qTilt  = app.BucketTiltAngledegEditField.Value;
            mLoad  = app.LoadkgEditField.Value;
            stopTime = app.MaxTimesecEditField.Value;

            minMachParams = evalin('base','minMachParams');
            testSettings = evalin('base','testSettings');
            Init = evalin('base','Init');
            modelName = app.ModelNameEditField.Value;
            evalstr = ['[extInput_Mining_Loader_Sweep, minMachParams, Init] = test_MLSweep_braking(''' modelName ''',minMachParams,testSettings,Init,' num2str(vSpd) ',' num2str(qSlope) ',' num2str(qStr) ',' num2str(qLift) ',' num2str(qTilt) ',' num2str(mLoad) ',' num2str(stopTime) ');'];
            disp(evalstr);
            evalin('base',evalstr);
            % [extInput_Mining_Loader_Sweep, minMachParams, Init] = test_MLSweep_braking(modelName,minMachParams,testSettings,Init,vSpd,qSlope,qStr,qLift,qTilt,mLoad,stopTime);
            %assignin('base','Init',Init);
            %assignin('base','minMachParams',minMachParams);
            %assignin('base','extInput_Mining_Loader_Sweep',extInput_Mining_Loader_Sweep);
            assignin('base','stopTime',stopTime);
            evalin('base',['out=sim(''' modelName ''');'])
            evalin('base','sm_mining_loader_plot1whlspd(out.logsout_Mining_Loader_Sweep,minMachParams.contact.tireRad/1000,minMachParams.contact.tireRad/1000,true);')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoaderBrakeTestUIFigure = uifigure('Visible', 'off');
            app.LoaderBrakeTestUIFigure.Name = 'Loader Brake Test';
            app.TargetSpeedkphEditFieldLabel = uilabel(app.LoaderBrakeTestUIFigure);
            app.TargetSpeedkphEditFieldLabel.Text = 'Target Speed (kph)';
            app.TargetSpeedkphEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.TargetSpeedkphEditField.Limits = [-12 12];
            app.TargetSpeedkphEditField.Value = 5;
            app.SteeringAngledegEditFieldLabel = uilabel(app.LoaderBrakeTestUIFigure);
            app.SteeringAngledegEditFieldLabel.Text = 'Steering Angle (deg)';
            app.SteeringAngledegEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.SteeringAngledegEditField.Limits = [-42 42];
            app.SlopedegEditField_2Label = uilabel(app.LoaderBrakeTestUIFigure);
            app.SlopedegEditField_2Label.Text = 'Slope (deg)';
            app.SlopedegEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.SlopedegEditField.Limits = [-12 12];
            app.EditFieldLabel = uilabel(app.LoaderBrakeTestUIFigure);
            app.EditFieldLabel.Text = 'Boom Lift Angle (deg)';
            app.BoomLiftAngledegEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.BoomLiftAngledegEditField.Limits = [0 45];
            app.EditFieldLabel_2 = uilabel(app.LoaderBrakeTestUIFigure);
            app.EditFieldLabel_2.Text = 'Bucket Tilt Angle (deg)';
            app.BucketTiltAngledegEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.BucketTiltAngledegEditField.Limits = [-45 45];
            app.Label = uilabel(app.LoaderBrakeTestUIFigure);
            app.Label.Text = 'Mining Loader Brake Test Conditions';
            app.ModelNameEditFieldLabel = uilabel(app.LoaderBrakeTestUIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'text');
            app.ModelNameEditField.Value = 'Mining_Loader_Sweep';
            app.RunTestButton = uibutton(app.LoaderBrakeTestUIFigure, 'push');
            app.RunTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunTestButtonPushed, true);
            app.RunTestButton.Text = 'Run Test';
            app.MaxTimesecEditFieldLabel = uilabel(app.LoaderBrakeTestUIFigure);
            app.MaxTimesecEditFieldLabel.Text = 'Max Time (sec)';
            app.MaxTimesecEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.MaxTimesecEditField.Value = 40;
            app.LoadkgEditFieldLabel = uilabel(app.LoaderBrakeTestUIFigure);
            app.LoadkgEditFieldLabel.Text = 'Load (kg)';
            app.LoadkgEditField = uieditfield(app.LoaderBrakeTestUIFigure, 'numeric');
            app.LoadkgEditField.Limits = [0 14000];
            % Show the figure after all components are created
            app.LoaderBrakeTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_MLSweep_braking_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LoaderBrakeTestUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.LoaderBrakeTestUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoaderBrakeTestUIFigure)
        end
    end
end